classdef AppSA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Button_load_plants         matlab.ui.control.Button
        Log                        matlab.ui.control.TextArea
        Label                      matlab.ui.control.Label
        DropDown_SynthesisMethods  matlab.ui.control.DropDown
        Label_2                    matlab.ui.control.Label
        DropDown_SynthesisOption   matlab.ui.control.DropDown
        Button_count               matlab.ui.control.Button
    end

    
    properties (Access = public)
        p_LoadedClasses % connected and loaded classes
        p_LoadedMethods % connected methods in classes
        p_LoadedData %data with transfer functions
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            AppConnector.detectSynthesisClasses(app);
            app.DropDown_SynthesisMethodsValueChanged;
            %TEST
            app.Button_load_plantsPushed;
        end

        % Value changed function: DropDown_SynthesisMethods
        function DropDown_SynthesisMethodsValueChanged(app, event)
            %set method dropdown depends on class dropdown selection
            value = app.DropDown_SynthesisMethods.Value;
            [r,k] = find(app.p_LoadedClasses == ...
                value);
            AppConnector.detectInternalMethods(app.p_LoadedClasses(r,2),app);            
        end

        % Button pushed function: Button_load_plants
        function Button_load_plantsPushed(app, event)
            app.p_LoadedData = load('InputData\ObjectData.mat');
        end

        % Button pushed function: Button_count
        function Button_countPushed(app, event)
            %call synthesis method depends on selected class and synthesis
            %option
            valueClass = app.DropDown_SynthesisMethods.Value;
            valueMethod = app.DropDown_SynthesisOption.Value;
            [r_c,k_c] = find(app.p_LoadedClasses == ...
                valueClass);
            [r_m,k_m] = find(app.p_LoadedMethods == ...
                valueMethod);
            eval(['[Wsys,Wreg] = ',...
                convertStringsToChars(app.p_LoadedClasses(r_c,2)),'.'...
                ,convertStringsToChars(app.p_LoadedMethods(r_m,2)),...
                '(app.p_LoadedData.Wobj);']);
            %TODO: in service app class
            figure
            step(Wsys)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button_load_plants = uibutton(app.UIFigure, 'push');
            app.Button_load_plants.ButtonPushedFcn = createCallbackFcn(app, @Button_load_plantsPushed, true);
            app.Button_load_plants.Text = 'Загрузить';
            app.Log = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Метод синтеза:';
            app.DropDown_SynthesisMethods = uidropdown(app.UIFigure);
            app.DropDown_SynthesisMethods.Items = {};
            app.DropDown_SynthesisMethods.ValueChangedFcn = createCallbackFcn(app, @DropDown_SynthesisMethodsValueChanged, true);
            app.DropDown_SynthesisMethods.Value = {};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Опции синтеза:';
            app.DropDown_SynthesisOption = uidropdown(app.UIFigure);
            app.DropDown_SynthesisOption.Items = {};
            app.DropDown_SynthesisOption.Value = {};
            app.Button_count = uibutton(app.UIFigure, 'push');
            app.Button_count.ButtonPushedFcn = createCallbackFcn(app, @Button_countPushed, true);
            app.Button_count.Text = 'Считать';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppSA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end